classdef MHost2_dev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadParametersButton       matlab.ui.control.Button
        RewardButton               matlab.ui.control.StateButton
        yoffsetEditFieldLabel      matlab.ui.control.Label
        yoffset                    matlab.ui.control.NumericEditField
        ygainEditFieldLabel        matlab.ui.control.Label
        ygain                      matlab.ui.control.NumericEditField
        xoffsetEditFieldLabel      matlab.ui.control.Label
        xoffset                    matlab.ui.control.NumericEditField
        xgain                      matlab.ui.control.NumericEditField
        xgainEditFieldLabel        matlab.ui.control.Label
        DIRButton                  matlab.ui.control.Button
        Dir                        matlab.ui.control.EditField
        FinalizeButton             matlab.ui.control.Button
        SubjectNameEditField       matlab.ui.control.EditField
        SubjectNameEditFieldLabel  matlab.ui.control.Label
        STOPButton                 matlab.ui.control.StateButton
        RuncalibrationCheckBox     matlab.ui.control.CheckBox
        STARTButton                matlab.ui.control.Button
        InformationTextArea        matlab.ui.control.TextArea
        InformationTextAreaLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        LiveParameters % Description
    end

    methods (Access = public)
        function RewardButtonPushed_external(app,~,e)
            reward_supp(app,e)
        end

        function insToTxtbox(app,str)
            txtstr=sprintf('%s\n%s\n',str, string(get(app.InformationTextArea,'Value')));
            set(app.InformationTextArea,'Value',txtstr);
        end

        function savestate(~,int,e)
            Screen('Close')
            %% save parameters and trials
            bckpname=fullfile(e.dir,...
                [e.subject_name,datestr(now, '_yymmdd')],...
                datestr(now, 'HHMMSS'));

            mkdir(bckpname)
            addpath(bckpname);

            taskpath=fullfile(fileparts(which('Main_function')), 'Tasks');
            copyfile(taskpath, fullfile(bckpname,'Tasks'))

            fileID = fopen(fullfile(bckpname, 'description.txt'),'W');
            fprintf(fileID,'%s','starting trial: ',num2str(int.trial.trialnum));
            fclose(fileID);
            %% back up data up to this point
            save(fullfile(bckpname(1:end-7),'e'),'e')
        end
    end

    methods (Access = private)

        function [inter,e] = Run_Experiment(app,varargin)
            %% initialize filepaths for xippmex and debug mode etc
            addpath(genpath(fileparts(which('Main_function'))));                        % add subfolders with all functions
            rmpath(what('DEBUG').path) % remove DEBUG code override
            xippath=genpath(fileparts(which('xippmex')));

            %% set all the parameters up
            inter=internal;
            inter=setupPsychToolbox(inter);

            if nargin==1
                inter.diode_pos=[0,inter.screenYpixels-50,50,inter.screenYpixels];
                inter=makeparams(inter);
            elseif nargin == 2
                if isa(varargin{1},'experiment')
                    e=varargin{1};
                    inter.diode_pos=[0,inter.screenYpixels-50,50,inter.screenYpixels];
                    inter=makeparams(inter);
                else
                    inter=varargin{1};
                end
            elseif nargin ==3
                inter=varargin{1};
                e=varargin{2};
            end

            %% set up the output structure
            e=experiment; % initialize the object
            e.subject_name = app.SubjectNameEditField.Value;
            e.dir = app.Dir.Value;

            %% DEBUG??
            debug=1;
            if debug
                app.insToTxtbox('!!!RUNNING IN DEBUG MODE!!! YOU MIGHT (WILL) LOSE YOUR XIPPMEX PATH')

                rmpath(xippath)
                addpath(what('DEBUG').path)
            end
            %% set up daq. Modify the specifics in the setupDAQ function
            dq = setupDAQ(app);

            %% Calibrate eye voltage
            inter.eye=eyeinfo(app); % init eye

            if get(app.RuncalibrationCheckBox,'Value')
                inter.eye = inter.eye.eyeCalib(inter,app);
            end

            %% trial runs start here technically
            set(app.FinalizeButton,'Enable','off')

            while app.FinalizeButton.Enable == 'off'
            %% shove target info into gui table if no parameters were loaded
            targnames=fieldnames(inter.targets);
            for i=1:numel(targnames)
                pos{i,:}=[];
                for ii=1:size(inter.targets.(targnames{i}).position,1)
                    pos{i,:}=[pos{i,:} num2str(inter.targets.(targnames{i}).position(ii,:)) ';'];
                end
                col{i,:}=num2str(inter.targets.(targnames{i}).color);
                sz{i,:}=num2str(inter.targets.(targnames{i}).size);
            end
            targtable=[targnames,pos,col,sz];

            set(app.LiveParameters.TargetsTable,'Data',targtable);

            %% shove interval info into gui table if no parameters were loaded
            intnames=fieldnames(inter.intervals);
            for i=1:numel(intnames)
                tim{i,:}=[];
                for ii=1:size(inter.intervals.(intnames{i}).duration,1)
                    tim{i,:}=[tim{i,:} num2str(inter.intervals.(intnames{i}).duration(ii,:)) ';'];
                end
                prob{i,:}=num2str(inter.intervals.(intnames{i}).prob);
                sound{i,:}=num2str(inter.intervals.(intnames{i}).sound);
            end
            targtable=[intnames,tim,prob,sound];

            set(app.LiveParameters.IntervalsTable,'Data',targtable);

            %% call trial types
                set(app.STOPButton,'Enable','on')

                while ~app.STOPButton.Value
                    %
                    % get the logic for which trial to do next
                    inter.ttypeslogic = app.LiveParameters.FilesTable.Data;
                    tlist=[];
                    for i=1:size(inter.ttypeslogic,1)
                        tlist=[tlist repmat(inter.ttypeslogic(i,1),...
                            1,cell2mat(inter.ttypeslogic(i,2)))];
                    end
                    randTrial=randi(length(tlist));
                    evalstring=['[inter]=' tlist{randTrial}(1:end-2) '(inter);'];
                    inter.trial.ttype=tlist{randTrial}(1:end-2);

                    % see if any targets changed
                    ttable=app.LiveParameters.TargetsTable.Data;
                    for i=1:size(ttable,1)
                        eval(['temp=[' ttable{i,2} '];']);
                        inter.targets.(ttable{i,1}).position=temp;
                        inter.targets.(ttable{i,1}).color=str2num(ttable{i,3});
                        inter.targets.(ttable{i,1}).size=str2num(ttable{i,4});
                        clear temp
                    end

                    % see if any intervals changed
                    ttable=app.LiveParameters.IntervalsTable.Data;
                    for i=1:size(ttable,1)
                        eval(['temp=[' ttable{i,2} '];']);
                        inter.intervals.(ttable{i,1}).duration=temp;
                        inter.intervals.(ttable{i,1}).prob=str2num(ttable{i,3});
                        inter.intervals.(ttable{i,1}).sound=str2num(ttable{i,4});
                        clear temp
                    end

                    inter.trial.tstarttime=getsecs;
                    inter.trialnumpersistent = inter.trialnumpersistent+1;
                    inter.trial.trialnum=inter.trialnumpersistent;
                    app.insToTxtbox(['trial number', num2str(inter.trial.trialnum)]);

                    %see if calibration changed
                    inter.eye=eyeinfo(app);

                    xippmex('trial', 'recording');%start trellis
                    %% ******** trial in this loop ********
                    while inter.runtrial==1 %&& ~app.STOPButton.Value
                        tic

                        eval(evalstring);
                        Screen('AsyncFlipCheckEnd',inter.window_main);
                        Screen2('Flip',inter,[],[],2);

                        if app.RewardButton.Value
                            inter.reward(inter.getint('reward'))
                            app.RewardButton.Value=0;
                        end

                        inter.rewcheck;
                        drawnow
                                            toc
                    end
                    inter.trial.tstoptime=getsecs;

                    %% post-trial

                    inter.runtrial=1; % activate next trial

                    if inter.diode_on==1 % incase diode was on at the end, turn it off
                        inter.diodeflip
                        Screen2('Flip',inter,[],[],2);
                    end

                    ttime=(inter.trial.tstoptime-inter.trial.tstarttime)*1000;
                    d=data;   %initialize empty data
                    d.eyepos=inter.eye.geteye(ttime);
                    d.neural_data='placeholder';
                    d.eyesync=xippmex('cont',10241,ttime,'1ksps');
                    inter.trial.data=d;

                    e.trial(inter.trial.trialnum)=inter.trial;
                    e.trial(inter.trial.trialnum).System_Properties=inter;
                    xippmex('trial', 'stopped');

                    inter.trial=trial;

                    while (inter.trial.tstoptime+inter.intervals.iti.getint)>getsecs %% ITI
                        Screen2('Flip',inter);
                        drawnow
                    end
                end
                %% ending procedure

                app.savestate(inter,e)

                set(app.STOPButton,'enable','off')

                set(app.STOPButton,'Value',0)
                app.FinalizeButton.Enable = 'on';
                clearvars -except app inter e

                keyboard

                app.FinalizeButton.Enable = 'off';
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','clear')

            addpath(genpath(fileparts(which('LiveParams'))));

            defDir=fullfile(pwd,'data');
            set(app.Dir,'Value',defDir)

            app.LiveParameters = LiveParams(app);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            app.insToTxtbox('Starting trials')
            app.STARTButton.Enable='off';
            app.RuncalibrationCheckBox.Enable = 'off';
            app.SubjectNameEditField.Editable = 'off';

            isinter = evalin( 'base', 'exist(''inter'',''var'') == 1' );
            ise = evalin( 'base', 'exist(''e'',''var'') == 1' );

            if isinter && ~ise
                inter=evalin('base','inter');

                [inter, e]=Run_Experiment(app,inter);

            elseif ise && ~isinter
                e=evalin('base','e');

                [inter, e]=Run_Experiment(app,e);

            elseif ise && isinter
                e=evalin('base','e');
                inter=evalin('base','inter');

                [inter, e]=Run_Experiment(app,inter,e);

            elseif ~ise && ~isinter
                [inter, e]=Run_Experiment(app);
            end

            assignin('base','e',e)
            assignin('base','inter',inter)

            app.STARTButton.Enable='on';
            app.RuncalibrationCheckBox.Enable = 'on';
            app.SubjectNameEditField.Editable = 'on';

            app.RuncalibrationCheckBox.Value = 0;
        end

        % Button pushed function: FinalizeButton
        function FinalizeButtonPushed(app, event)


            app.insToTxtbox('archiving data')
            dirname=fullfile(app.Dir.Value, ...
                [app.SubjectNameEditField.Value,datestr(now, '_yymmdd')]);
            if ~isfile([dirname '.zip'])
                zip(dirname, dirname)
            else
                addon=string(length(dir([dirname '*']))+1);
                zip(append(dirname,'_', addon),...
                    dirname)
            end
            rmdir(dirname,'s')
            evalin( 'base', 'clear' );
            sca;

            delete(app.LiveParameters)
            app.delete
            
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            if value==1
                app.insToTxtbox('stopping trials please wait')
            end
        end

        % Button pushed function: DIRButton
        function DIRButtonPushed(app, event)
            dir=uigetdir;
            set(app.Dir,'Value',dir)
        end

        % Callback function
        function SetCalibrationButtonValueChanged(app, event)
            value = app.SetCalibrationButton.Value;
            if value==1
                insToTxtbox(app, 'setting calibration please wait')
            end
        end

        % Value changed function: RewardButton
        function RewardButtonValueChanged(app, event)
            value = app.RewardButton.Value;

        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            fpath=fullfile(path,file);
            if contains(fpath(end-4:end),'.mat')
                load(fpath)
            elseif contains(fpath(end-4:end),'.zip')
                tempdir=fullfile(path,'temp');
                unzip(fpath,tempdir)
                finde=dir([tempdir '\*\']);
                eidx=cellfun(@(x) contains(x,'.mat'),{finde.name});
                load(fullfile(finde(eidx).folder,finde(eidx).name))
                dos_cmd = sprintf( 'rmdir /S /Q "%s"', tempdir );
                system( dos_cmd );
            end


            app.ygain.Value = e.trial(end).System_Properties.eye.ygain;
            app.yoffset.Value = e.trial(end).System_Properties.eye.yoffset;
            app.xgain.Value = e.trial(end).System_Properties.eye.xgain;
            app.xoffset.Value = e.trial(end).System_Properties.eye.xoffset;

            app.SubjectNameEditField.Value = e.subject_name;
            app.Dir.Value=e.dir;

            app.LiveParameters.FilesTable.Data=e.trial(end).System_Properties.ttypeslogic;

            %load target info
            targnames=fieldnames(e.trial(end).System_Properties.targets);
            for i=1:numel(targnames)
                pos{i,:}=[];
                for ii=1:size(e.trial(end).System_Properties.targets.(targnames{i}).position,1)
                    pos{i,:}=[pos{i,:} num2str(e.trial(end).System_Properties.targets.(targnames{i}).position(ii,:)) ';'];
                end
                col{i,:}=num2str(e.trial(end).System_Properties.targets.(targnames{i}).color);
                sz{i,:}=num2str(e.trial(end).System_Properties.targets.(targnames{i}).size);
            end
            targtable=[targnames,pos,col,sz];

            set(app.LiveParameters.TargetsTable,'Data',targtable);

            %load interval info
            intnames=fieldnames(e.trial(end).System_Properties.intervals);
            for i=1:numel(intnames)
                tim{i,:}=[];
                for ii=1:size(e.trial(end).System_Properties.intervals.(intnames{i}).duration,1)
                    tim{i,:}=[tim{i,:} num2str(e.trial(end).System_Properties.intervals.(intnames{i}).duration(ii,:)) ';'];
                end
                prop{i,:}=num2str(e.trial(end).System_Properties.intervals.(intnames{i}).prob);
                sound{i,:}=num2str(e.trial(end).System_Properties.intervals.(intnames{i}).sound);
            end
            targtable=[intnames,tim,prop,sound];

            set(app.LiveParameters.IntervalsTable,'Data',targtable);

            %create internal structure
            inter=e.trial(end).System_Properties; % get old properties
            inter.trial=trial;
            inter.trial = trial;
            inter.trialstarted = 0;
            inter.trialnumpersistent=0;
            inter.diode_on = 0;

            e.trial=trial;

            assignin('base','e',e)
            assignin('base','inter',inter)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.LiveParameters)
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InformationTextAreaLabel = uilabel(app.UIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.InformationTextArea.Editable = 'off';
            app.InformationTextArea.Value = {' '};
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RuncalibrationCheckBox = uicheckbox(app.UIFigure);
            app.RuncalibrationCheckBox.Text = 'Run calibration';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.SubjectNameEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectNameEditFieldLabel.Text = 'Subject Name';
            app.SubjectNameEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectNameEditField.Value = 'NAME';
            app.FinalizeButton = uibutton(app.UIFigure, 'push');
            app.FinalizeButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeButtonPushed, true);
            app.FinalizeButton.Enable = 'off';
            app.FinalizeButton.Text = 'Finalize';
            app.Dir = uieditfield(app.UIFigure, 'text');
            app.DIRButton = uibutton(app.UIFigure, 'push');
            app.DIRButton.ButtonPushedFcn = createCallbackFcn(app, @DIRButtonPushed, true);
            app.DIRButton.Text = 'DIR';
            app.xgainEditFieldLabel = uilabel(app.UIFigure);
            app.xgainEditFieldLabel.Text = 'x gain';
            app.xgain = uieditfield(app.UIFigure, 'numeric');
            app.xoffset = uieditfield(app.UIFigure, 'numeric');
            app.xoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.xoffsetEditFieldLabel.Text = 'x offset';
            app.ygain = uieditfield(app.UIFigure, 'numeric');
            app.ygainEditFieldLabel = uilabel(app.UIFigure);
            app.ygainEditFieldLabel.Text = 'y gain';
            app.yoffset = uieditfield(app.UIFigure, 'numeric');
            app.yoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.yoffsetEditFieldLabel.Text = 'y offset';
            app.RewardButton = uibutton(app.UIFigure, 'state');
            app.RewardButton.ValueChangedFcn = createCallbackFcn(app, @RewardButtonValueChanged, true);
            app.RewardButton.Text = 'Reward';
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MHost2_dev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end